classdef compinterest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FinalDepositButton            matlab.ui.control.Button
        FinalDepositEditField         matlab.ui.control.NumericEditField
        FinalDepositEditFieldLabel    matlab.ui.control.Label
        Knob                          matlab.ui.control.DiscreteKnob
        KnobLabel                     matlab.ui.control.Label
        YearsEditField                matlab.ui.control.NumericEditField
        YearsEditFieldLabel           matlab.ui.control.Label
        NominalannualinterestrateEditField  matlab.ui.control.NumericEditField
        NominalannualinterestrateEditFieldLabel  matlab.ui.control.Label
        InitialDepositEditField       matlab.ui.control.NumericEditField
        InitialDepositEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Done by Mohd Wafa 302539.
    % Exercise 3 : Use the App Designer to create an application computing
    % the compound interest.
    properties (Access = public)
        n % the value of n based on the knob value
        
       
    end
    
    methods (Access = public)
        % func(app) is the function to calculate the value of final deposit
        % with parameters only app, so as to display the output.
        function results = func(app)
            A_0 = app.InitialDepositEditField.Value;
            r = app.NominalannualinterestrateEditField.Value;
            t = app.YearsEditField.Value;


            results = A_0*(1+r/app.n).^(app.n*t);


        end
    end
    
    methods (Access = public)
        % func2(app,nt) is a function that calculates the final deposit 
        % with additional parameter of nt. This function is helpful in
        % plotting the barplot with increasing value of nt from a for loop.
        function results = func2(app,nt)
            A_0 = app.InitialDepositEditField.Value;
            r = app.NominalannualinterestrateEditField.Value;
           

            results = A_0*(1+r/app.n).^(nt);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % setting the default values at startup
            app.InitialDepositEditField.Value = 100;
            app.NominalannualinterestrateEditField.Value = 0.05;
            app.YearsEditField.Value = 4;
            app.Knob.Value = "Monthly";
            app.n = 12;
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
           
            % setting the value for n based on the input from the knob
            if app.Knob.Value =="Annually"
                 app.n = 1;
            elseif app.Knob.Value== "Monthly"
                 app.n = 12;
            elseif  app.Knob.Value == "Weekly"
                 app.n = 48;
            elseif  app.Knob.Value =="Daily"
                 app.n = 365;
            end
        end

        % Button pushed function: FinalDepositButton
        function FinalDepositButtonPushed(app, event)
            % Below is the calculation using the formula straight to the
            % values
%               app.FinalDepositEditField.Value = app.InitialDepositEditField.Value * (1 + (app.NominalannualinterestrateEditField.Value/ app.n)).^(app.n* app.YearsEditField.Value);
              
% and here is the calculation using the func() function to calculate 
% compound interest
             app.FinalDepositEditField.Value = func(app);
             
             % plotting the barplot.
             UIAxesButtonDown(app,event);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           % X axis is the value from 1 to n*t   
            X = 1: app.n*app.YearsEditField.Value; 
%initializing vector for Y axis
Y =[];


                   for d = 1:app.n* app.YearsEditField.Value
% inserting values of the final deposit from the for loop
 Y(end+1) = func2(app,d);
                   end  
                  
         % plotting bar graph
           b  = bar(app.UIAxes,X,Y);
         
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Bargraph'; ''})
            xlabel(app.UIAxes, 'n*t')
            ylabel(app.UIAxes, 'Final Deposit')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.InitialDepositEditFieldLabel = uilabel(app.UIFigure);
            app.InitialDepositEditFieldLabel.Text = {'Initial Deposit'; ''};
            app.InitialDepositEditField = uieditfield(app.UIFigure, 'numeric');
            app.NominalannualinterestrateEditFieldLabel = uilabel(app.UIFigure);
            app.NominalannualinterestrateEditFieldLabel.Text = 'Nominal annual interest rate';
            app.NominalannualinterestrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearsEditFieldLabel = uilabel(app.UIFigure);
            app.YearsEditFieldLabel.Text = 'Years';
            app.YearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearsEditField.Limits = [0 Inf];
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Annually', 'Monthly', 'Weekly', 'Daily'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Annually';
            app.FinalDepositEditFieldLabel = uilabel(app.UIFigure);
            app.FinalDepositEditFieldLabel.Text = 'Final Deposit';
            app.FinalDepositEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalDepositButton = uibutton(app.UIFigure, 'push');
            app.FinalDepositButton.ButtonPushedFcn = createCallbackFcn(app, @FinalDepositButtonPushed, true);
            app.FinalDepositButton.Text = 'Final Deposit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compinterest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end